classdef Inputwindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatenEintragenUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        InputButton             matlab.ui.control.Button
        PrintButton             matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainapp; % Description
        Printsetupdialog % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp=mainapp;
            app.UITable.ColumnName=mainapp.UITable.ColumnName(2:15);
            for i=1:14
                vartypes{i}='string';
            end
            
             ss=app.UITable.ColumnName;
            tables=table('Size',[1,14],'VariableTypes',vartypes,'VariableNames',ss);
            tables(1,:)=array2table(zeros([1,14]));
            app.UITable.Data=tables;
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            app.Printsetupdialog=Printsetup(app);


        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            selectioncon=uiconfirm(app.DatenEintragenUIFigure,'Sind die eingegebenen Daten korrekt?','Warnung',"Options",{'Ja','Cancel'});
            if strcmp(selectioncon,'Ja')
               Adddata(app.mainapp,app.UITable.Data);
            else
            
            end

        end

        % Close request function: DatenEintragenUIFigure
        function DatenEintragenUIFigureCloseRequest(app, event)
            delete(app.Printsetupdialog);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatenEintragenUIFigure = uifigure('Visible', 'off');
            app.DatenEintragenUIFigure.Name = 'Daten Eintragen';
            app.DatenEintragenUIFigure.CloseRequestFcn = createCallbackFcn(app, @DatenEintragenUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.DatenEintragenUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.PrintButton = uibutton(app.GridLayout, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.InputButton = uibutton(app.GridLayout, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            % Show the figure after all components are created
            app.DatenEintragenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inputwindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatenEintragenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatenEintragenUIFigure)
        end
    end
end