classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatabearbeitungUIFigure      matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        RohdatenTab                  matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        GefilterteDatenTab           matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        UITable2                     matlab.ui.control.Table
        UpdateButton                 matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        InputButton                  matlab.ui.control.Button
        SuchenButton                 matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
        ContextMenu                  matlab.ui.container.ContextMenu
        DatenmarkierungenlschenMenu  matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        PrintMenu                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DialogSuchen % Description
        selecteditem % Description
        suchedrow % Description
        suchedcol % Description
        suchitemsnum % Description
        Suchdialog % Description
        Inputdialog % Description
    end
    
    properties (Access = public)
        myTable %
    end


    methods (Access = public)
        
        function updatetable(app,suchvalue)
   
            a=zeros(size(app.myTable));
            removeStyle(app.UITable);
            

             for i=1:14
                 a(find(strncmpi(table2array(app.myTable(:,i+1)),suchvalue(i),strlength(suchvalue(i)))),i)=1;
             end
            b=a>0;
            sb=sum(b');
            bstr=suchvalue~=" ";
            strb=sum(bstr);
            if strb~=0
            app.suchitemsnum=strb;
            rowsb=find(sb==strb);
            colsb=find(bstr~=0)+1;
            if isempty(rowsb)
                 warndlg('Keine ergebnisse gefunden！', 'Warnung');
            end
              selectecolnum=ones([1,size(colsb,2)]);
              selectedcell(:,1)=kron(rowsb,selectecolnum)';
              selecteroes=repmat(colsb,1,size(rowsb,2))';
              selectedcell(:,2)=selecteroes;
                
            s = uistyle('BackgroundColor','yellow');
            s2 = uistyle('BackgroundColor','cyan');
            selet=uistyle('BackgroundColor','green');
            
            addStyle(app.UITable,s,'row',rowsb);
            addStyle(app.UITable,s2,'cell',selectedcell);
            if app.selecteditem~=[]
            addStyle(app.UITable,selet,'row',app.selecteditem);
            end
            app.suchedrow=rowsb;
            app.suchedcol=selectedcell;
            else
                app.suchitemsnum=0;
            end


        end
        
        function Adddata(app,data)
             keytable=table('Size',[1,1],'VariableTypes',["string"],'VariableNames',app.UITable.ColumnName(1));
             num=size(app.UITable.Data,1)+1;
             keytable(1,1)=array2table(num);
             inputdata=[keytable,data];
            oldtable=app.UITable.Data;
            newtable=[oldtable;inputdata];
            app.UITable.Data=newtable;
            
        end
    end
    
    methods (Access = private)
        
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsm');
            if file==0
                
            else
            try Tabledata=readtable(fullfile(path,file),'Sheet','Simulationsbuch','Range','A:O','PreserveVariableNames',true);
                vartypes=string([1,size(Tabledata,2)]);
                for i=1:size(Tabledata,2)
                    vartypes{i}='string';
                end
                newT=table('Size',size(Tabledata),'VariableTypes',vartypes,'VariableNames',Tabledata.Properties.VariableNames);
                for n=1:size(Tabledata,1)
                    newT(n,:)=Tabledata(n,:);
                end
                app.myTable=newT;
                app.UITable.Data=newT;
                app.UITable.ColumnName=Tabledata.Properties.VariableNames;
                
            catch ME
                if(strcmp(ME.identifier,'MATLAB:spreadsheet:book:openSheetName'))
                warndlg('Es existiert kein Arbeitsblatt mit dem Namen Simulationsbuch, bitte überprüfen Sie, ob der Tabellenname korrekt ist!','Warnung');
                else                        
                    throw(ME);
                end
            end
            end
        end

        % Button pushed function: SuchenButton
        function SuchenButtonPushed(app, event)
            if isempty(app.UITable.Data)
                warndlg('Datei wurde nicht importiert', 'Warnung');
            else
            
            app.SuchenButton.Enable='off';
            app.Suchdialog=Suchenwindow(app,app.SuchenButton);
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)

            removeStyle(app.UITable);
            indices = event.Indices;
            if ~isempty(indices)
            app.selecteditem=indices(1);
            if(~isempty(app.suchitemsnum) && ~isempty(app.suchedcol) && ~isempty(app.suchedrow))
            s = uistyle('BackgroundColor','yellow');
            s2 = uistyle('BackgroundColor','cyan');
  
            addStyle(app.UITable,s,'row',app.suchedrow);
            addStyle(app.UITable,s2,'cell',app.suchedcol);
            end
            selet=uistyle('BackgroundColor','green');
            addStyle(app.UITable,selet,'row',app.selecteditem);
            else
            end


        end

        % Callback function
        function DeleteButtonPushed(app, event)
            
                selection=uiconfirm(app.DatabearbeitungUIFigure,'Ausgewählte Daten löschen?','Warnung',"Options",{'Ja','Nein'});
                if strcmp(selection,'Ja')
                    app.UITable.Data(app.selecteditem,:)=[];
                    
                end
            
        end

        % Close request function: DatabearbeitungUIFigure
        function DatabearbeitungUIFigureCloseRequest(app, event)
            selectioncon=uiconfirm(app.DatabearbeitungUIFigure,'Programm beendet?','Warnung',"Options",{'Ja','Cancel'});
            if strcmp(selectioncon,'Ja')
               delete(app.Suchdialog);
               delete(app.Inputdialog);
               delete(app);
            else
            end


        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            if isempty(app.UITable.Data)==true
            else
            app.Inputdialog=Inputwindow(app);
            end
        end

        % Callback function
        function ModusButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModusButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Daten können bearbeitet werden'
                    app.UITable.ColumnEditable=true
                case 'Daten sind nicht editierbar'
                    app.UITable.ColumnEditable=false
            end
        end

        % Callback function
        function AlleDatenlschenMenuSelected(app, event)
            selection=uiconfirm(app.DatabearbeitungUIFigure,'Alle Daten löschen?','Warnung',"Options",{'Ja','Nein'});
            if strcmp(selection,'Ja')
                    app.UITable.Data=[];
                    
            end
        end

        % Menu selected function: DatenmarkierungenlschenMenu
        function DatenmarkierungenlschenMenuSelected(app, event)
            app.suchedcol=[];
            app.suchedrow=[];
            removeStyle(app.UITable);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if isempty(app.UITable.Data)==true
            else
            copytable=app.UITable.Data;
            table2array(app.UITable.Data(:,7))
            for n=1:size(app.UITable.Data(:,7),1)
                idarray(n)=str2num(table2array(app.UITable.Data(n,7)));
            end
           maxsimuID=max(idarray);
           for i=1:maxsimuID
               pairidx=find(table2array(copytable(:,7))==num2str(i));
               if size(pairidx,1)>=2
                   copytable(pairidx,:)=[];
               end

           end
           app.UITable2.ColumnName=app.UITable.ColumnName;
           app.UITable2.Data=copytable;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile('Exported_Data.xlsx');
            if file==0
            else
            path=fullfile([path,file]);
            writetable(app.UITable.Data,path);
            end
            
        end

        % Menu selected function: PrintMenu
        function PrintMenuSelected(app, event)
            if size(app.UITable2.Data,1)==0

            else
                try
                 delete("Filtered data.xls");
                 filename = 'Filtered data.xls';
                 toprinttable=app.UITable2.Data;
                 toprinttable(:,[1 5 7])=[];
                 writetable(toprinttable,filename,'Sheet',1);
                 Excel = actxserver('excel.application');
                 Excel.visible = 1;
                 Workbooks = Excel.Workbooks;
                 % Make Excel visible
                 % Open Excel file
                 currentFolder = pwd;
             
                 
                 Workbook=Workbooks.Open([currentFolder,'\',filename]);
                
                  
                 Excel.ActiveSheet.PageSetup.LeftMargin = Excel.InchesToPoints(0.05);
                 Excel.ActiveSheet.PageSetup.RightMargin = Excel.InchesToPoints(0.05);
                 Excel.ActiveSheet.Range('A:L').EntireRow.AutoFit;
                 Excel.ActiveSheet.Range('A:L').EntireColumn.AutoFit;
                Excel.ActiveSheet.Range('A:L').Columns.ColumnWidth=10;
                Excel.ActiveSheet.Range('G:G').Columns.ColumnWidth=20;
                 Excel.ActiveSheet.Range('A:L').WrapText = true;
                 Excel.ActiveSheet.Range('A:L').HorizontalAlignment = -4108; % center alignment
                 Excel.ActiveSheet.Range('A:L').VerticalAlignment = -4108; % center alignment
                  Excel.ActiveSheet.Range('A:L').Borders.LineStyle = 1; % continious line
                 Excel.ActiveSheet.PageSetup.Orientation = 'xlLandscape'; 
                 Excel.ActiveSheet.Range('A:L').Font.Size=7;
                 Excel.ActiveWorkbook.PrintOut(1,[],1,true);
                catch ME
                if strcmp('MATLAB:table:write:FileOpenInAnotherProcess',ME.identifier)
                    warndlg('Excel Datei ist in Excel geöffnet, bitte schließen Sie Excel zuerst', 'Error');
                else
                    
                    warndlg(ME.identifier,'Error');
                end
                end
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatabearbeitungUIFigure = uifigure('Visible', 'off');
            app.DatabearbeitungUIFigure.Name = 'Data bearbeitung';
            app.DatabearbeitungUIFigure.CloseRequestFcn = createCallbackFcn(app, @DatabearbeitungUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.DatabearbeitungUIFigure);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SuchenButton = uibutton(app.GridLayout, 'push');
            app.SuchenButton.ButtonPushedFcn = createCallbackFcn(app, @SuchenButtonPushed, true);
            app.SuchenButton.Text = 'Suchen';
            app.InputButton = uibutton(app.GridLayout, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UpdateButton = uibutton(app.GridLayout, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RohdatenTab = uitab(app.TabGroup);
            app.RohdatenTab.Title = 'Rohdaten';
            app.GridLayout2 = uigridlayout(app.RohdatenTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GefilterteDatenTab = uitab(app.TabGroup);
            app.GefilterteDatenTab.Title = 'Gefilterte Daten';
            app.GridLayout3 = uigridlayout(app.GefilterteDatenTab);
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.ContextMenu = uicontextmenu(app.DatabearbeitungUIFigure);
            app.DatenmarkierungenlschenMenu = uimenu(app.ContextMenu);
            app.DatenmarkierungenlschenMenu.MenuSelectedFcn = createCallbackFcn(app, @DatenmarkierungenlschenMenuSelected, true);
            app.DatenmarkierungenlschenMenu.Text = 'Datenmarkierungen löschen';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.DatabearbeitungUIFigure);
            app.PrintMenu = uimenu(app.ContextMenu2);
            app.PrintMenu.MenuSelectedFcn = createCallbackFcn(app, @PrintMenuSelected, true);
            app.PrintMenu.Text = 'Print';
            % Assign app.ContextMenu2
            app.UITable2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.DatabearbeitungUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatabearbeitungUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatabearbeitungUIFigure)
        end
    end
end